classdef project_ppt < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        NghiemTab                       matlab.ui.container.Tab
        EndEditField                    matlab.ui.control.NumericEditField
        EndEditFieldLabel               matlab.ui.control.Label
        StartEditField                  matlab.ui.control.NumericEditField
        StartEditFieldLabel             matlab.ui.control.Label
        nEditField                      matlab.ui.control.NumericEditField
        SlnlpEditFieldLabel             matlab.ui.control.Label
        resultEditField                 matlab.ui.control.NumericEditField
        KtquEditFieldLabel              matlab.ui.control.Label
        ppListBox                       matlab.ui.control.ListBox
        PhngphpListBoxLabel             matlab.ui.control.Label
        errorEditField                  matlab.ui.control.NumericEditField
        NhpsaischophpEditFieldLabel     matlab.ui.control.Label
        NhpkhongphnlynghimABLabel       matlab.ui.control.Label
        BEditField                      matlab.ui.control.NumericEditField
        BEditFieldLabel                 matlab.ui.control.Label
        AEditField                      matlab.ui.control.NumericEditField
        AEditFieldLabel                 matlab.ui.control.Label
        inputEditField                  matlab.ui.control.EditField
        NhpphngtrnhEditFieldLabel       matlab.ui.control.Label
        RUNButton                       matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        NoisuyTab                       matlab.ui.container.Tab
        HoiquyTab                       matlab.ui.container.Tab
        KetquacuaphuongtrinhEditField   matlab.ui.control.EditField
        KetquacuaphuongtrinhEditFieldLabel  matlab.ui.control.Label
        KetquadudoanEditField           matlab.ui.control.NumericEditField
        KetquadudoanEditFieldLabel      matlab.ui.control.Label
        PhuongphapListBox               matlab.ui.control.ListBox
        PhuongphapListBoxLabel          matlab.ui.control.Label
        NhapgiatridudoanEditField       matlab.ui.control.NumericEditField
        NhapgiatridudoanEditFieldLabel  matlab.ui.control.Label
        yEditField                      matlab.ui.control.EditField
        yEditFieldLabel                 matlab.ui.control.Label
        xEditField                      matlab.ui.control.EditField
        xEditFieldLabel                 matlab.ui.control.Label
        RunHoiQuy                       matlab.ui.control.Button
        UIAxes2                         matlab.ui.control.UIAxes
        DaohamTab                       matlab.ui.container.Tab
        TichphanTab                     matlab.ui.container.Tab
        GioithieunhomTab                matlab.ui.container.Tab
    end

    
    properties (Access = private)
        flag = 0; % Description
    end
    
    methods (Access = private)
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Các thông số khởi tạo ban đầu
            app.AEditField.Value = 0;
            app.BEditField.Value = 0.5;
            app.EndEditField.Value = 0.5;
            app.StartEditField.Value = -0.2;
            app.errorEditField.Value = 0.01;

            % Mặc định cho listbox
            app.ppListBox.Value = 'Chia đôi';
            app.inputEditField.Value = '(x-1)^2-1/2*exp(x)';% f(x)

            % QUAN TRỌNG: Kích hoạt nút RUN tự động
            % Gọi hàm callback của nút RUN như một hàm bình thường
            % Tham số thứ 2 là event, truyền [] (rỗng) vì không click chuột thật
            RUNButtonPushed(app, []);

                        %% ====== KHỞI TẠO TAB HỒI QUY (SỬA) ======
            app.xEditField.Value = '1:9';
            app.yEditField.Value = '1, 1.5, 2, 3, 4, 5, 8, 10, 13';
            
            % Phương pháp mặc định (đúng tên: PhuongphapListBox)
            app.PhuongphapListBox.Value = 'Tuyen tinh';
            
            % Giá trị dự đoán mặc định
            app.NhapgiatridudoanEditField.Value = 6;
            
            % Xóa kết quả cũ (đúng tên: KetquacuaphuongtrinhEditField)
            app.KetquacuaphuongtrinhEditField.Value = '';
            app.KetquadudoanEditField.Value = 0;
            
            % Chuẩn bị trục đúng cho Hồi quy: UIAxes2 (KHÔNG dùng UIAxes)
            cla(app.UIAxes2);
            grid(app.UIAxes2,'on');
            title(app.UIAxes2,'Đồ thị hồi quy');
            xlabel(app.UIAxes2,'X');
            ylabel(app.UIAxes2,'Y');


        end

        % Callback function: EndEditField, RUNButton, StartEditField, 
        % ...and 1 other component
        function RUNButtonPushed(app, event)
            disp("--------- DEBUG: Nhấn nút được! ---------");
            % ----- Dữ liệu đầu vào -----
            endp = app.EndEditField.Value;
            startp = app.StartEditField.Value;
            func_str = app.inputEditField.Value;     

            % VD: x^2 -> x.^2
            draw_str = strrep(func_str, '^', '.^');
            draw_str = strrep(draw_str, '*', '.*');
            draw_str = strrep(draw_str, '/', './');
            func = str2func(['@(x)', draw_str]);

            % ----- Thông số - tính toán vẽ đồ thị ------
            fs = 10000;
            t = linspace(startp, endp, fs);
            y = func(t);

            % ------ Vẽ đồ thị hàm số ------
            plot(app.UIAxes, t, y, 'b','LineWidth', 1.6);
            grid(app.UIAxes, 'on');

            % ------ Tìm nghiệm xấp xỉ ------
            a = app.AEditField.Value;
            b = app.BEditField.Value;
            error = app.errorEditField.Value;
            
            func_calc = str2func(['@(x)', func_str]);
            selectedOption = app.ppListBox.Value;
            try
                switch selectedOption
                    case 'Chia đôi'
                        [x, n] = chiadoi(func_calc, a, b, error);
                    case 'Lặp'
                        [x, n] = lap(func_calc, a, b, error);
                        hold(app.UIAxes, 'on');
                        yLine = @(k)(k);
                        plot(app.UIAxes, t, yLine(t), 'g-', 'LineWidth', 1.0);
                        xline(app.UIAxes, x, 'k--', 'LineWidth', 1.0);
                        plot(app.UIAxes, x, 0, 'ro', 'MarkerFaceColor', 'r');
                        hold(app.UIAxes, 'off');
                    case 'Newton (tiếp tuyến)'
                        [x, n] = newton_raphson(func_str, a, b, error);
                end
                
                % Hiển thị kết quả
                app.nEditField.Value = n;
                app.resultEditField.Value = double(x);
                
                % Vẽ điểm nghiệm
                hold(app.UIAxes, 'on');
                yline(app.UIAxes, 0, 'Color', 'k', 'LineWidth', 1.5);
                xline(app.UIAxes, 0, 'Color', 'k', 'LineWidth', 1.5);
                plot(app.UIAxes, x, func_calc(x), 'ro', 'MarkerFaceColor', 'r');
                hold(app.UIAxes, 'off');
            catch ME
                %uialert(app.UIFigure, ME.message, 'Lỗi');%Debug
            end
            
        end

        % Value changed function: inputEditField
        function inputEditFieldValueChanged(app, event)
            currentStr = app.inputEditField.Value;
            if isempty(currentStr)
                app.flag = 0;

                currentMethod = app.ppListBox.Value;
                switch currentMethod
                    case 'Chia đôi'        
                        app.inputEditField.Value = '(x-1)^2-1/2*exp(x)';% f(x)
                    case 'Lặp'
                        app.inputEditField.Value = '1 - sqrt(2)/2*exp(1/2*x)'; % g(x)
                    case 'Newton (tiếp tuyến)'
                        app.inputEditField.Value = '(x-1)^2-1/2*exp(x)';% f(x)
                end
            else
                app.flag = 1;
            end
        end

        % Callback function
        function StartEditFieldValueChanged(app, event)
            
            
        end

        % Callback function
        function EndEditFieldValueChanged(app, event)
            
            
        end

        % Value changed function: ppListBox
        function ppListBoxValueChanged(app, event)
            selectedOption = app.ppListBox.Value;

            if app.flag == 0
                switch selectedOption
                    case 'Chia đôi'        
                        app.inputEditField.Value = '(x-1)^2-1/2*exp(x)';% f(x)
                    case 'Lặp'
                        app.inputEditField.Value = '1 - sqrt(2)/2*exp(1/2*x)'; % g(x)
                    case 'Newton (tiếp tuyến)'
                        app.inputEditField.Value = '(x-1)^2-1/2*exp(x)';% f(x)
                end
            else 
                return;
            end
        end

        % Value changed function: AEditField
        function AEditFieldValueChanged(app, event)
            app.StartEditField.Value = app.AEditField.Value - 5;
        end

        % Value changed function: BEditField
        function BEditFieldValueChanged(app, event)
            app.EndEditField.Value = app.BEditField.Value + 5;
        end

        % Callback function
        function errorEditFieldValueChanged(app, event)
            
            
        end

        % Value changed function: resultEditField
        function resultEditFieldValueChanged(app, event)
            
            
        end

        % Value changed function: nEditField
        function nEditFieldValueChanged(app, event)
            
            
        end

        % Callback function
        function RUNButtonPushed2(app, event)
     x = str2num(app.xEditField.Value); %#ok<ST2NM>
    y = str2num(app.yEditField.Value); %#ok<ST2NM>

    if isempty(x) || isempty(y)
        uialert(app.UIFigure,'Chưa nhập x hoặc y','Lỗi');
        return
    end

    if length(x) ~= length(y)
        uialert(app.UIFigure,'x và y phải cùng số phần tử','Lỗi');
        return
    end

    % ===== 2. CHỌN PHƯƠNG PHÁP & GỌI HÀM =====
    method = app.PhuongphapListBox.Value;

    switch method
        case 'Tuyen tinh'
            [a1, a0, y_fit, eqn] = regressionLinear(x, y); %#ok<NASGU>

        case 'Ham mu'
            try
                [a, b, y_fit, eqn] = regressionPower(x, y); %#ok<NASGU>
            catch ME
                uialert(app.UIFigure, ME.message, 'Lỗi');
                return
            end

        case 'Ham logarit'
            try
                [a, b, y_fit, eqn] = regressionLog(x, y); %#ok<NASGU>
            catch ME
                uialert(app.UIFigure, ME.message, 'Lỗi');
                return
            end
    end

    % ===== 3. HIỂN THỊ PHƯƠNG TRÌNH =====
    app.KetquacuaphuongtrinhEditField.Value = eqn;

    % ===== 4. VẼ ĐỒ THỊ (ĐÚNG UIAxes2) =====
    cla(app.UIAxes2);
    hold(app.UIAxes2,'on');

    plot(app.UIAxes2, x, y, 'bo', 'MarkerFaceColor','b');

    xx = linspace(min(x), max(x), 200);

    switch method
        case 'Tuyen tinh'
            yy = a1*xx + a0;
        case 'Ham mu'
            yy = a*xx.^b;
        case 'Ham logarit'
            yy = a*log(xx) + b;
    end

    plot(app.UIAxes2, xx, yy, 'r', 'LineWidth', 2);

    grid(app.UIAxes2,'on');
    legend(app.UIAxes2, {'Dữ liệu','Hồi quy'}, 'Location','best');
    hold(app.UIAxes2,'off');

    % ===== 5. DỰ ĐOÁN =====
    x0 = app.NhapgiatridudoanEditField.Value;

    switch method
        case 'Tuyen tinh'
            y0 = a1*x0 + a0;

        case 'Ham mu'
            if x0 <= 0
                uialert(app.UIFigure,'x0 phải > 0','Lỗi');
                return
            end
            y0 = a*x0^b;

        case 'Ham logarit'
            if x0 <= 0
                uialert(app.UIFigure,'x0 phải > 0','Lỗi');
                return
            end
            y0 = a*log(x0) + b;
    end

    app.KetquadudoanEditField.Value = y0;

    % ===== 6. VẼ ĐIỂM DỰ ĐOÁN =====
    hold(app.UIAxes2,'on');
    plot(app.UIAxes2, x0, y0, 'ro', 'MarkerSize', 8, 'LineWidth', 2);
    hold(app.UIAxes2,'off');
        end

        % Size changed function: HoiquyTab
        function HoiquyTabSizeChanged(app, event)
            position = app.HoiquyTab.Position;
            
        end

        % Button pushed function: RunHoiQuy
        function RunHoiQuyButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.NghiemTab = uitab(app.TabGroup);
            app.NghiemTab.Title = 'Nghiệm';
            app.UIAxes = uiaxes(app.NghiemTab);
            title(app.UIAxes, 'Đồ thị hàm số')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'f(x)')
            zlabel(app.UIAxes, 'Z')
            app.RUNButton = uibutton(app.NghiemTab, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.NhpphngtrnhEditFieldLabel = uilabel(app.NghiemTab);
            app.NhpphngtrnhEditFieldLabel.Text = 'Nhập phương trình';
            app.inputEditField = uieditfield(app.NghiemTab, 'text');
            app.inputEditField.ValueChangedFcn = createCallbackFcn(app, @inputEditFieldValueChanged, true);
            app.AEditFieldLabel = uilabel(app.NghiemTab);
            app.AEditFieldLabel.Text = 'A';
            app.AEditField = uieditfield(app.NghiemTab, 'numeric');
            app.AEditField.ValueChangedFcn = createCallbackFcn(app, @AEditFieldValueChanged, true);
            app.BEditFieldLabel = uilabel(app.NghiemTab);
            app.BEditFieldLabel.Text = 'B';
            app.BEditField = uieditfield(app.NghiemTab, 'numeric');
            app.BEditField.ValueChangedFcn = createCallbackFcn(app, @BEditFieldValueChanged, true);
            app.NhpkhongphnlynghimABLabel = uilabel(app.NghiemTab);
            app.NhpkhongphnlynghimABLabel.Text = 'Nhập khoảng phân ly nghiệm [A, B]';
            app.NhpsaischophpEditFieldLabel = uilabel(app.NghiemTab);
            app.NhpsaischophpEditFieldLabel.Text = 'Nhập sai số cho phép';
            app.errorEditField = uieditfield(app.NghiemTab, 'numeric');
            app.errorEditField.ValueChangedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.PhngphpListBoxLabel = uilabel(app.NghiemTab);
            app.PhngphpListBoxLabel.Text = 'Phương pháp';
            app.ppListBox = uilistbox(app.NghiemTab);
            app.ppListBox.Items = {'Chia đôi', 'Lặp', 'Newton (tiếp tuyến)'};
            app.ppListBox.ValueChangedFcn = createCallbackFcn(app, @ppListBoxValueChanged, true);
            app.ppListBox.Value = 'Chia đôi';
            app.KtquEditFieldLabel = uilabel(app.NghiemTab);
            app.KtquEditFieldLabel.Text = 'Kêt quả';
            app.resultEditField = uieditfield(app.NghiemTab, 'numeric');
            app.resultEditField.ValueChangedFcn = createCallbackFcn(app, @resultEditFieldValueChanged, true);
            app.SlnlpEditFieldLabel = uilabel(app.NghiemTab);
            app.SlnlpEditFieldLabel.Text = 'Số lần lặp';
            app.nEditField = uieditfield(app.NghiemTab, 'numeric');
            app.nEditField.ValueChangedFcn = createCallbackFcn(app, @nEditFieldValueChanged, true);
            app.StartEditFieldLabel = uilabel(app.NghiemTab);
            app.StartEditFieldLabel.Text = 'Start';
            app.StartEditField = uieditfield(app.NghiemTab, 'numeric');
            app.StartEditField.ValueChangedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.EndEditFieldLabel = uilabel(app.NghiemTab);
            app.EndEditFieldLabel.Text = 'End';
            app.EndEditField = uieditfield(app.NghiemTab, 'numeric');
            app.EndEditField.ValueChangedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.NoisuyTab = uitab(app.TabGroup);
            app.NoisuyTab.Title = 'Noi suy';
            app.HoiquyTab = uitab(app.TabGroup);
            app.HoiquyTab.SizeChangedFcn = createCallbackFcn(app, @HoiquyTabSizeChanged, true);
            app.HoiquyTab.Title = 'Hoi quy';
            app.UIAxes2 = uiaxes(app.HoiquyTab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.RunHoiQuy = uibutton(app.HoiquyTab, 'push');
            app.RunHoiQuy.ButtonPushedFcn = createCallbackFcn(app, @RunHoiQuyButtonPushed, true);
            app.RunHoiQuy.Text = 'Run';
            app.xEditFieldLabel = uilabel(app.HoiquyTab);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.HoiquyTab, 'text');
            app.yEditFieldLabel = uilabel(app.HoiquyTab);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.HoiquyTab, 'text');
            app.NhapgiatridudoanEditFieldLabel = uilabel(app.HoiquyTab);
            app.NhapgiatridudoanEditFieldLabel.Text = 'Nhap gia tri  du doan';
            app.NhapgiatridudoanEditField = uieditfield(app.HoiquyTab, 'numeric');
            app.PhuongphapListBoxLabel = uilabel(app.HoiquyTab);
            app.PhuongphapListBoxLabel.Text = 'Phuong phap';
            app.PhuongphapListBox = uilistbox(app.HoiquyTab);
            app.PhuongphapListBox.Items = {'Tuyen tinh', 'Ham mu', 'Ham logarit'};
            app.PhuongphapListBox.Value = 'Tuyen tinh';
            app.KetquadudoanEditFieldLabel = uilabel(app.HoiquyTab);
            app.KetquadudoanEditFieldLabel.Text = 'Ket qua du doan';
            app.KetquadudoanEditField = uieditfield(app.HoiquyTab, 'numeric');
            app.KetquacuaphuongtrinhEditFieldLabel = uilabel(app.HoiquyTab);
            app.KetquacuaphuongtrinhEditFieldLabel.Text = 'Ket qua cua phuong trinh';
            app.KetquacuaphuongtrinhEditField = uieditfield(app.HoiquyTab, 'text');
            app.DaohamTab = uitab(app.TabGroup);
            app.DaohamTab.Title = 'Dao ham';
            app.TichphanTab = uitab(app.TabGroup);
            app.TichphanTab.Title = 'Tich phan';
            app.GioithieunhomTab = uitab(app.TabGroup);
            app.GioithieunhomTab.Title = 'Gioi thieu nhom';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = project_ppt

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end